classdef are_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AURORALabel                 matlab.ui.control.Label
        PlaceholderLabel            matlab.ui.control.Label
        CurrentPatientLabel         matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        Slider_4                    matlab.ui.control.Slider
        Slider_4Label               matlab.ui.control.Label
        Slider_3                    matlab.ui.control.Slider
        Slider_3Label               matlab.ui.control.Label
        Slider_2                    matlab.ui.control.Slider
        Label_2                     matlab.ui.control.Label
        MissingDICOMLabel           matlab.ui.control.Label
        RegistrationResultsLabel_2  matlab.ui.control.Label
        UITable_3                   matlab.ui.control.Table
        ImageViewerLabel            matlab.ui.control.Label
        BeginRegistrationButton     matlab.ui.control.Button
        AlgorithmDropDown           matlab.ui.control.DropDown
        AlgorithmDropDownLabel      matlab.ui.control.Label
        AlgorithmSelectorLabel      matlab.ui.control.Label
        LoadSelectedPatientButton   matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        NewPatientNameLabel         matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        PatientSelectorLabel        matlab.ui.control.Label
        ExitButton                  matlab.ui.control.Button
        HelpButton                  matlab.ui.control.Button
        ExportButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        AutomaticRegistrationResultAssessorLabel  matlab.ui.control.Label
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)

        SelectedCellIndex = 0; % Currently selected cell row index
        Conn = NaN; % Initialize database connection
        CurrentCT = [];
        CurrentMRI = [];
        CurrentDICOM = [];
        PatientLoaded = false;
        
    end
    
    methods (Access = private)
        
        function ChangeSelectedRow(app, row)
            app.SelectedCellIndex = row;
            removeStyle(app.UITable);
            
            if row ~= 0
                s = uistyle('BackgroundColor',[0.3010 0.7450 0.9330]);
                addStyle(app.UITable,s,'row',row);
            else
                app.LoadSelectedPatientButton.Enable = 'off';
                app.MissingDICOMLabel.Visible = false;
            end
        end
        
        function UpdateDBVal(app, row, col, val)
            id = app.UITable.Data.id(row);
            query = "update patients set ";
            
            if col == 1
                query = query + "name = '" + val + "' where id = " + num2str(id);
            elseif col == 2
                query = query + "date = '" + val + "' where id = " + num2str(id);
            elseif col == 3
                logical = fetch(app.Conn, "select ct from patients where id = " + num2str(id));
                logical = logical{1,1};
                if logical == true
                    query = query + "ct = false where id = " + num2str(id);
                elseif logical == false
                    query = query + "ct = true where id = " + num2str(id);
                end
            elseif col == 4
                logical = fetch(app.Conn, "select mri from patients where id = " + num2str(id));
                logical = logical{1,1};
                if logical == true
                    query = query + "mri = false where id = " + num2str(id);
                elseif logical == false
                    query = query + "mri = true where id = " + num2str(id);
                end
            end
            
            execute(app.Conn, query);
            app.UITable.Data = flipud(fetch(app.Conn, "select * from patients order by added"));
        end
        
        function DeleteDBVal(app, row)
            sqlwrite(app.Conn,'deleted',table(app.UITable.Data{row,1},app.UITable.Data{row,2},app.UITable.Data{row,5},'VariableNames',{'name' 'date' 'id'}));
            execute(app.Conn,"delete from patients where id = " + num2str(app.UITable.Data{row,5}));
            app.UITable.Data = flipud(fetch(app.Conn, "select * from patients order by added"));
        end
        
        function dispAxial(app, imagematrix, z) % Update axial image
            im = double(squeeze(imagematrix(:,:,z)));
            im = imadjust(im/max(im(:)),[0 1],[0 1],2.5);
            imshow(im, 'Parent', app.UIAxes);
        end
        
        function dispSagittal(app, imagematrix, y) % Update sagittal image
            im = double(squeeze(imagematrix(:,y,:)));
            im = imadjust(im.'/max(im(:)),[0 1],[0 1],2.5);
            imshow(im, 'Parent', app.UIAxes_2);
        end
        
        function dispCoronal(app, imagematrix, x) % Update coronal image
            im = double(squeeze(imagematrix(x,:,:)));
            im = imadjust(im.'/max(im(:)),[0 1],[0 1],2.5);
            imshow(im, 'Parent', app.UIAxes_3);
        end
        
        function [threedarray, xthickness, ythickness, zthickness] = gatherImages(app, folder)
        %GATHERIMAGES looks through a folder, gets all DICOM files and assembles
        %them into a viewable 3d format.
        
            d = sortDirectory(app, folder); %Sort in ascending order of instance number
            topimage = dicomread(d(1,:));
            metadata = dicominfo(d(1,:));
        
            [group1, element1] = dicomlookup('PixelSpacing');
            [group2, element2] = dicomlookup('SliceThickness');
            resolution = metadata.(dicomlookup(group1, element1));
            xthickness = resolution(1); ythickness = resolution(2);
            zthickness = metadata.(dicomlookup(group2, element2));
        
            threedarray = zeros(size(topimage,1),size(topimage,2),size(d,1));
            threedarray(:,:,1) = topimage;
        
            for i = 2:size(d,1)
               threedarray(:,:,i) = dicomread(d(i,:));
            end
        
        end %end gatherImages(folder)
        
        function d = sortDirectory(app, folder)
        %SORTDIRECTORY sorts based on instance number
            
            cd(folder);
            d = ls('*.dcm');
            m = size(d,1);
        
            [group, element] = dicomlookup('InstanceNumber');
            sdata(m) = struct('imagename','','instance',0);
        
            for i = 1:m
                metadata = dicominfo(d(i,:));
                position = metadata.(dicomlookup(group, element));
                sdata(i) = struct('imagename',d(i,:),'instance',position);
            end
        
            [~, order] = sort([sdata(:).instance],'ascend');
            sorted = sdata(order).';
        
            for i = 1:m
                d(i,:) = sorted(i).imagename;
            end
        
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlaceholderLabel.Text = "";
            
            % Change directory to folder containing functions and DICOMs
            cd("C:\Users\cristi\Desktop\612 Comp\auto_registration_evaluator\MATLAB");
            
            % Connect to are_db database
            app.Conn = postgresql('ARE_Database','postgres','cristianSQL');
            app.UITable.Data = flipud(fetch(app.Conn, "select * from patients order by added"));
            
            % Set DICOM missing invisible initially
            app.MissingDICOMLabel.Visible = false;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            maxAdded = fetch(app.Conn, "select max(added) from patients");
            maxAdded = maxAdded{1,1};
            if isnan(maxAdded)
                maxAdded = 0;
            end
            
            newId = round(rand*1000000);
            patientsId = fetch(app.Conn, "select id from patients");
            deletedId = fetch(app.Conn, "select id from deleted");
            while ismember(newId, patientsId{:,:}) == true || ismember(newId, deletedId{:,:}) == true
                newId = round(rand*1000000);
            end
            
            sqlwrite(app.Conn,'patients',table(convertCharsToStrings(value),convertCharsToStrings(date),false,false,newId,maxAdded+1,'VariableNames',{'name' 'date' 'ct' 'mri' 'id' 'added'}));
            sqlwrite(app.Conn,'paths',table(newId,"","",'VariableNames',{'id' 'ct_path' 'mri_path'}));
            app.UITable.Data = flipud(sqlread(app.Conn,'patients'));
            
            app.EditField.Value = "";
            
            if app.SelectedCellIndex ~= 0
                ChangeSelectedRow(app, app.SelectedCellIndex+1);
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.Conn);
            delete(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            UpdateDBVal(app, indices(1), indices(2), newData);
            
            if (indices(2) == 3 || indices(2) == 4) && app.UITable.Data{indices(1), indices(2)} == true
                path = uigetdir;
                
                if path == 0
                    UpdateDBVal(app, indices(1), indices(2), false);
                elseif indices(2) == 3
                    query = "update paths set ct_path = '" + convertCharsToStrings(path) + "' where id = " + num2str(app.UITable.Data.id(indices(1)));
                    execute(app.Conn, query);
                elseif indices(2) == 4
                    query = "update paths set mri_path = '" + convertCharsToStrings(path) + "' where id = " + num2str(app.UITable.Data.id(indices(1)));
                    execute(app.Conn, query);
                end
            end

            if app.UITable.Data{indices(1), 3} == true && app.UITable.Data{indices(1), 4} == true && app.SelectedCellIndex == indices(1)
                app.LoadSelectedPatientButton.Enable = 'on';
                app.MissingDICOMLabel.Visible = false;
            elseif app.SelectedCellIndex == indices(1) && app.UITable.Data{indices(1), 3} == false || app.UITable.Data{indices(1), 4} == false
                app.LoadSelectedPatientButton.Enable = 'off';
                app.MissingDICOMLabel.Visible = true;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if app.UITable.Data{indices(1), 3} == true && app.UITable.Data{indices(1), 4} == true
                app.LoadSelectedPatientButton.Enable = 'on';
                app.MissingDICOMLabel.Visible = false;
            else
                app.LoadSelectedPatientButton.Enable = 'off';
                app.MissingDICOMLabel.Visible = true;
            end
            
            ChangeSelectedRow(app, indices(1));
        end

        % Button pushed function: LoadSelectedPatientButton
        function LoadSelectedPatientButtonPushed(app, event)
            removeStyle(app.UITable);
            id = app.UITable.Data.id(app.SelectedCellIndex);
            name = app.UITable.Data.name(app.SelectedCellIndex);
            name = name{1,1};
            app.PlaceholderLabel.Text = name;
            app.PatientLoaded = true;
            app.SelectedCellIndex = 0;
            app.LoadSelectedPatientButton.Enable = 'off';
            app.AlgorithmDropDown.Enable = 'on';
            app.BeginRegistrationButton.Enable = 'on';
            
            paths = fetch(app.Conn, "select ct_path, mri_path from paths where id = " + num2str(id));
            app.CurrentCT = gatherImages(app, paths{1,1});
            app.CurrentMRI = gatherImages(app, paths{1,2});
            
            if convertCharsToStrings(app.Switch.Value) == "CT"
                app.CurrentDICOM = app.CurrentCT;
            else
                app.CurrentDICOM = app.CurrentMRI;
            end
            
            dispAxial(app, app.CurrentDICOM, round(size(app.CurrentCT, 3) / 2));
            dispSagittal(app, app.CurrentDICOM, round(size(app.CurrentCT, 2) / 2));
            dispCoronal(app, app.CurrentDICOM, round(size(app.CurrentCT, 1) / 2));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close(app.Conn);
            delete(app);
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            
            if key == "delete"
                if app.SelectedCellIndex ~= 0
                    DeleteDBVal(app, app.SelectedCellIndex);
                    ChangeSelectedRow(app, 0);
                end
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            msgbox(["\fontsize{11}\bfEntering a new patient:"; ...
                "\rmType a patient name in the text box and hit ENTER."; ...
                ""; ...
                "\bfSelecting a patient:"; ...
                "\rmClick on a row in the patient table."; ...
                ""; ...
                "\bfDeleting a patient:"; ...
                "\rmSelect a patient and hit DELETE."], CreateStruct);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            
            dispAxial(app, app.CurrentDICOM, round((size(app.CurrentDICOM, 3) - 1) * changingValue / 100) + 1);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            
            dispCoronal(app, app.CurrentDICOM, round((size(app.CurrentDICOM, 1) - 1) * changingValue / 100) + 1);
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            
            dispSagittal(app, app.CurrentDICOM, round((size(app.CurrentDICOM, 2) - 1) * changingValue / 100) + 1);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = convertCharsToStrings(app.Switch.Value);
            
            if app.PatientLoaded == true
                if value == "CT"
                    app.CurrentDICOM = app.CurrentCT;
                else
                    app.CurrentDICOM = app.CurrentMRI;
                end
                
                dispAxial(app, app.CurrentDICOM, round((size(app.CurrentDICOM, 3) - 1) * app.Slider_2.Value / 100) + 1);
                dispSagittal(app, app.CurrentDICOM, round((size(app.CurrentDICOM, 2) - 1) * app.Slider_4.Value / 100) + 1);
                dispCoronal(app, app.CurrentDICOM, round((size(app.CurrentDICOM, 1) - 1) * app.Slider_3.Value / 100) + 1);
            end
            
            
        end

        % Button pushed function: BeginRegistrationButton
        function BeginRegistrationButtonPushed(app, event)
            if app.AlgorithmDropDown.Value == "This Algorithm"
                app.UITable_3.Data = [app.UITable_3.Data; table("This Algorithm",87.4,"(+5,-1,0)",'VariableNames',{'Algorithm' 'Accuracy' 'Translation'})];
            elseif app.AlgorithmDropDown.Value == "That Algorithm"
                app.UITable_3.Data = [app.UITable_3.Data; table("That Algorithm",92.0,"(+7,0,+3)",'VariableNames',{'Algorithm' 'Accuracy' 'Translation'})];
            elseif app.AlgorithmDropDown.Value == "Other Algorithm"
                app.UITable_3.Data = [app.UITable_3.Data; table("Other Algorithm",66.8,"(-12,+5,+9)",'VariableNames',{'Algorithm' 'Accuracy' 'Translation'})];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Axial')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Sagittal')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Coronal')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.AutomaticRegistrationResultAssessorLabel = uilabel(app.UIFigure);
            app.AutomaticRegistrationResultAssessorLabel.Text = '                 - Automatic Registration Result Assessor';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PatientSelectorLabel = uilabel(app.UIFigure);
            app.PatientSelectorLabel.Text = 'Patient Selector';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Date'; 'CT'; 'MRI'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true false false];
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.NewPatientNameLabel = uilabel(app.UIFigure);
            app.NewPatientNameLabel.Text = 'New Patient Name';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Placeholder = 'Enter name...';
            app.LoadSelectedPatientButton = uibutton(app.UIFigure, 'push');
            app.LoadSelectedPatientButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectedPatientButtonPushed, true);
            app.LoadSelectedPatientButton.Enable = 'off';
            app.LoadSelectedPatientButton.Text = 'Load Selected Patient';
            app.AlgorithmSelectorLabel = uilabel(app.UIFigure);
            app.AlgorithmSelectorLabel.Text = 'Algorithm Selector';
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm:';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'This Algorithm', 'That Algorithm', 'Other Algorithm'};
            app.AlgorithmDropDown.Enable = 'off';
            app.AlgorithmDropDown.Value = 'This Algorithm';
            app.BeginRegistrationButton = uibutton(app.UIFigure, 'push');
            app.BeginRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginRegistrationButtonPushed, true);
            app.BeginRegistrationButton.Enable = 'off';
            app.BeginRegistrationButton.Text = 'Begin Registration';
            app.ImageViewerLabel = uilabel(app.UIFigure);
            app.ImageViewerLabel.Text = 'Image Viewer';
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Algorithm'; 'Accuracy (%)'; 'Translation (cm)'};
            app.UITable_3.RowName = {};
            app.RegistrationResultsLabel_2 = uilabel(app.UIFigure);
            app.RegistrationResultsLabel_2.Text = 'Registration Results';
            app.MissingDICOMLabel = uilabel(app.UIFigure);
            app.MissingDICOMLabel.Text = 'Missing DICOM!';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = '';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = '';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.MajorTicks = [];
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_4.MinorTicks = [];
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'CT', 'MRI'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'CT';
            app.CurrentPatientLabel = uilabel(app.UIFigure);
            app.CurrentPatientLabel.Text = 'Current Patient:';
            app.PlaceholderLabel = uilabel(app.UIFigure);
            app.PlaceholderLabel.Text = 'Placeholder';
            app.AURORALabel = uilabel(app.UIFigure);
            app.AURORALabel.Text = 'AURORA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = are_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end